classdef GUI_Morze < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TextArea_2Label    matlab.ui.control.Label
        TextArea_2         matlab.ui.control.TextArea
        Panel              matlab.ui.container.Panel
        PortDropDownLabel  matlab.ui.control.Label
        Port_List_Fnc      matlab.ui.control.DropDown
        BandRateKnobLabel  matlab.ui.control.Label
        Baud_Rate          matlab.ui.control.DiscreteKnob
        TransmitLampLabel  matlab.ui.control.Label
        Transmit_Lamp      matlab.ui.control.Lamp
        ReceiveLampLabel   matlab.ui.control.Label
        Receive_Lamp       matlab.ui.control.Lamp
        Scale_Power        matlab.ui.control.NinetyDegreeGauge
        Connect_Switch     matlab.ui.control.Switch
        Disconnect         matlab.ui.control.Button
        Panel_2            matlab.ui.container.Panel
        Panel_3            matlab.ui.container.Panel
        Label_2            matlab.ui.control.Label
        EditField          matlab.ui.control.EditField
    end

    
    properties (Access = private)
        PORT % Serial port
        TIMER % Timer upgrade
        MIN_RANGE % Scale_Power
        MAX_RANGE % Scale_Power
        ON_OFF          = 'Off';% Description
        
        
        MORZE_TABLE = ...
            [                              ...
            {uint8('120000'), {'А', 'а'} };...
            {uint8('211100'), {'Б', 'б'} };...
            {uint8('122000'), {'В', 'в'} };...
            {uint8('221000'), {'Г', 'г'} };...
            {uint8('211000'), {'Д', 'д'} };...
            {uint8('100000'), {'Е', 'е'} };...
            {uint8('111200'), {'Ж', 'ж'} };...
            {uint8('221100'), {'З', 'з'} };...
            {uint8('110000'), {'И', 'и'} };...
            {uint8('122200'), {'Й', 'й'} };...
            {uint8('212000'), {'К', 'к'} };...
            {uint8('121100'), {'Л', 'л'} };...
            {uint8('220000'), {'М', 'м'} };...
            {uint8('210000'), {'Н', 'н'} };...
            {uint8('222000'), {'О', 'о'} };...
            {uint8('122100'), {'П', 'п'} };...
            {uint8('121000'), {'Р', 'р'} };...
            {uint8('111000'), {'С', 'с'} };...
            {uint8('200000'), {'Т', 'т'} };...
            {uint8('112000'), {'У', 'у'} };...
            {uint8('112100'), {'Ф', 'ф'} };...
            {uint8('111100'), {'Х', 'х'} };...
            {uint8('212100'), {'Ц', 'ц'} };...
            {uint8('222100'), {'Ч', 'ч'} };...
            {uint8('222200'), {'Ш', 'ш'} };...
            {uint8('221200'), {'Щ', 'щ'} };...
            {uint8('122121'), {'Ъ', 'ъ'} };...
            {uint8('212200'), {'Ы', 'ы'} };...
            {uint8('211200'), {'Ь', 'ь'} };...
            {uint8('112110'), {'Э', 'э'} };...
            {uint8('112200'), {'Ю', 'ю'} };...
            {uint8('121200'), {'Я', 'я'} } ...
            ] % Description


        PARAMT         = -1; % Description
        NEW_TEXT % Description
        PARAMR         = -1; % Description
        RECEIVE_DATA % Description
    end
    
    methods (Access = private)
        
        function results= Rand_Function(app)
            RAND_MAX    =    2147483647;
            results     =    double(...
                app.MIN_RANGE + mod(rand(), RAND_MAX) * ...
                (app.MAX_RANGE - app.MIN_RANGE));
        end
        
        function Interface_Upgrade(app, ~)
            
            app.Scale_Power.Value        = Rand_Function(app);
                        
            if length(app.ON_OFF) ~= length(app.Connect_Switch.Value)
                
                app.ON_OFF = app.Connect_Switch.Value;
                
                if (length(app.Connect_Switch.Value) == length('On'))
                    
                    app.MAX_RANGE          = 25;
                    app.MIN_RANGE          = 15;
                    app.MAX_RANGE          = Rand_Function(app);
                    app.MIN_RANGE          = 15;
                    app.MIN_RANGE          = Rand_Function(app);
                    
                    app.PORT.BaudRate      = str2double(app.Baud_Rate.Value);
                    app.PORT.BytesAvailableFcnCount    ...
                        = 6;
                    app.PORT.BytesAvailableFcnMode     ...
                        = 'byte';
                    app.PORT.BytesAvailableFcn         ...
                        = @(~,~)app.Read_Data;
                    fopen(app.PORT);
                    
                    app.Disconnect.Text    = 'Connect';
                    app.Disconnect.BackgroundColor     ...
                        = [0, 1, 0];
                else
                    app.MAX_RANGE          = 10;
                    app.MIN_RANGE          = 5;
                    app.MAX_RANGE          = Rand_Function(app);
                    app.MIN_RANGE          = 0;
                    app.MIN_RANGE          = Rand_Function(app);
                    app.EditField.Value    = '';
                    app.TextArea_2.Value   = {''};
                    stopasync(app.PORT);
                    fclose(app.PORT);
                    app.Disconnect.Text    = 'Disconnect';
                    app.Disconnect.BackgroundColor     ...
                        = [1, 0, 0];
                end
            
            end
            
            if app.PARAMT == 0
                app.MAX_RANGE          = 10;
                app.MIN_RANGE          = 5;
                app.MAX_RANGE          = Rand_Function(app);
                app.MIN_RANGE          = 0;
                app.MIN_RANGE          = Rand_Function(app);
                
                app.Receive_Lamp.Color = [0, 0, 0] ./ 255;
                
                app.PARAMT = -1;
                
            elseif app.PARAMT <= 10 && app.PARAMT > 0
                app.PARAMT = app.PARAMT + 1;
            elseif app.PARAMT > 10
                app.TextArea_2.Value     ...
                                        = app.NEW_TEXT;
                app.PARAMT = 0;             
            end
            
            if app.PARAMR == 0
                
                app.MAX_RANGE          = 10;
                app.MIN_RANGE          = 5;
                app.MAX_RANGE          = Rand_Function(app);
                app.MIN_RANGE          = 0;
                app.MIN_RANGE          = Rand_Function(app);
                
                app.Transmit_Lamp.Color= [0, 0, 0] ./ 255;
                
                app.PARAMR = -1;
                
            elseif app.PARAMR <= 20 && app.PARAMR > 0
                app.PARAMR = app.PARAMR + 1;
            elseif app.PARAMR > 20
                fwrite(app.PORT, app.RECEIVE_DATA, 'uint8', 'async');
                app.PARAMR = 0;
            end

        end
        
        function Read_Data(app)
                
                app.MAX_RANGE          = 80;
                app.MIN_RANGE          = 50;
                app.MAX_RANGE          = Rand_Function(app);
                app.MIN_RANGE          = 25;
                app.MIN_RANGE          = Rand_Function(app);
                
                app.Receive_Lamp.Color = [164, 10, 2] ./ 255;
                
                Input_Data             = uint8( fread(app.PORT,6,'uint8')' );
                
                Old_Text               = app.TextArea_2.Value;
                
                if isempty(Old_Text{1})
                    Register            = 1;
                else
                    Register            = 2;
                end
                
                if Input_Data == app.MORZE_TABLE{1, 1}
                    Input_Data = app.MORZE_TABLE{1, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{2, 1}
                    Input_Data = app.MORZE_TABLE{2, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{3, 1}
                    Input_Data = app.MORZE_TABLE{3, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{4, 1}
                    Input_Data = app.MORZE_TABLE{4, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{5, 1}
                    Input_Data = app.MORZE_TABLE{5, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{6, 1}
                    Input_Data = app.MORZE_TABLE{6, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{7, 1}
                    Input_Data = app.MORZE_TABLE{7, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{8, 1}
                    Input_Data = app.MORZE_TABLE{8, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{9, 1}
                    Input_Data = app.MORZE_TABLE{9, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{10, 1}
                    Input_Data = app.MORZE_TABLE{10, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{11, 1}
                    Input_Data = app.MORZE_TABLE{11, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{12, 1}
                    Input_Data = app.MORZE_TABLE{12, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{13, 1}
                    Input_Data = app.MORZE_TABLE{13, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{14, 1}
                    Input_Data = app.MORZE_TABLE{14, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{15, 1}
                    Input_Data = app.MORZE_TABLE{15, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{16, 1}
                    Input_Data = app.MORZE_TABLE{16, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{17, 1}
                    Input_Data = app.MORZE_TABLE{17, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{18, 1}
                    Input_Data = app.MORZE_TABLE{18, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{19, 1}
                    Input_Data = app.MORZE_TABLE{19, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{20, 1}
                    Input_Data = app.MORZE_TABLE{20, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{21, 1}
                    Input_Data = app.MORZE_TABLE{21, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{22, 1}
                    Input_Data = app.MORZE_TABLE{22, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{23, 1}
                    Input_Data = app.MORZE_TABLE{23, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{24, 1}
                    Input_Data = app.MORZE_TABLE{24, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{25, 1}
                    Input_Data = app.MORZE_TABLE{25, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{26, 1}
                    Input_Data = app.MORZE_TABLE{26, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{27, 1}
                    Input_Data = app.MORZE_TABLE{27, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{28, 1}
                    Input_Data = app.MORZE_TABLE{28, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{29, 1}
                    Input_Data = app.MORZE_TABLE{29, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{30, 1}
                    Input_Data = app.MORZE_TABLE{30, 2}{Register};
                elseif Input_Data == app.MORZE_TABLE{31, 1}
                    Input_Data = app.MORZE_TABLE{31, 2}{Register};
                else
                    Input_Data = app.MORZE_TABLE{32, 2}{Register};
                end
            
                
                app.NEW_TEXT                   = ...
                    {[Old_Text{1}, num2str(Input_Data)]};
                
                app.PARAMT = 1;
            
        end
        

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %%    Закрытие порта, на случай непредвиденных обстоятельств
            close all
            delete(instrfindall);
            
            
            Port_List = instrhwinfo('serial');
            
            app.Port_List_Fnc.Items= Port_List.AvailableSerialPorts;
            app.PORT               = serial(app.Port_List_Fnc.Value);
            app.Baud_Rate.Value    = {num2str(app.PORT.BaudRate)};
            
            app.MAX_RANGE          = 10;
            app.MIN_RANGE          = 5;
            app.MAX_RANGE          = Rand_Function(app);
            app.MIN_RANGE          = 0;
            app.MIN_RANGE          = Rand_Function(app);
            
            %                 Timer
            app.TIMER              = timer;
            app.TIMER.TimerFcn     = @(~,~)app.Interface_Upgrade;
            app.TIMER.ExecutionMod = 'fixedSpacing';
            app.TIMER.Period       = 5e-2;
            app.TIMER.BusyMode     = 'drop';
            start(app.TIMER);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.TIMER);
            stopasync(app.PORT);
            fclose(app.PORT);
            delete(app);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
                        
            app.MAX_RANGE          = 100;
            app.MIN_RANGE          = 62;
            app.MAX_RANGE          = Rand_Function(app);
            app.MIN_RANGE          = 38;
            app.MIN_RANGE          = Rand_Function(app);
            
            app.Transmit_Lamp.Color= [164, 10, 2] ./ 255;
            
            Changing_Value = event.Value;
            
            Changing_Value_Cell=cell(1,length(Changing_Value));
            
            for Number_Symbol = 1:length(Changing_Value)
                
                switch Changing_Value(Number_Symbol)
                    case app.MORZE_TABLE{1, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{1, 1};
                    case app.MORZE_TABLE{2, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{2, 1};
                    case app.MORZE_TABLE{3, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{3, 1};
                    case app.MORZE_TABLE{4, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{4, 1};
                    case app.MORZE_TABLE{5, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{5, 1};
                    case app.MORZE_TABLE{6, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{6, 1};
                    case app.MORZE_TABLE{7, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{7, 1};
                    case app.MORZE_TABLE{8, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{8, 1};
                    case app.MORZE_TABLE{9, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{9, 1};
                    case app.MORZE_TABLE{10, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{10, 1};
                    case app.MORZE_TABLE{11, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{11, 1};
                    case app.MORZE_TABLE{12, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{12, 1};
                    case app.MORZE_TABLE{13, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{13, 1};
                    case app.MORZE_TABLE{14, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{14, 1};
                    case app.MORZE_TABLE{15, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{15, 1};
                    case app.MORZE_TABLE{16, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{16, 1};
                    case app.MORZE_TABLE{17, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{17, 1};
                    case app.MORZE_TABLE{18, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{18, 1};
                    case app.MORZE_TABLE{19, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{19, 1};
                    case app.MORZE_TABLE{20, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{20, 1};
                    case app.MORZE_TABLE{21, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{21, 1};
                    case app.MORZE_TABLE{22, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{22, 1};
                    case app.MORZE_TABLE{23, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{23, 1};
                    case app.MORZE_TABLE{24, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{24, 1};
                    case app.MORZE_TABLE{25, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{25, 1};
                    case app.MORZE_TABLE{26, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{26, 1};
                    case app.MORZE_TABLE{27, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{27, 1};
                    case app.MORZE_TABLE{28, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{28, 1};
                    case app.MORZE_TABLE{29, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{29, 1};
                    case app.MORZE_TABLE{30, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{30, 1};
                    case app.MORZE_TABLE{31, 2}
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{31, 1};
                    otherwise
                        Changing_Value_Cell{Number_Symbol} = app.MORZE_TABLE{32, 1};
                end
                app.RECEIVE_DATA = Changing_Value_Cell{Number_Symbol};
                
            end
            
            app.PARAMR = 1;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            setAutoResize(app, app.UIFigure, true)
            app.TextArea_2Label = uilabel(app.UIFigure);
            app.TextArea_2Label.Text = 'Текст по морзянке (принятый)';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Editable = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Управление';
            app.PortDropDownLabel = uilabel(app.Panel);
            app.PortDropDownLabel.Text = 'Port';
            app.Port_List_Fnc = uidropdown(app.Panel);
            app.Port_List_Fnc.Items = {};
            app.Port_List_Fnc.Editable = 'on';
            app.Port_List_Fnc.Value = {};
            app.BandRateKnobLabel = uilabel(app.Panel);
            app.BandRateKnobLabel.Text = 'Band Rate';
            app.Baud_Rate = uiknob(app.Panel, 'discrete');
            app.Baud_Rate.Items = {'4800', '9600', '19200', '38400', '57600', '115200'};
            app.Baud_Rate.Value = '4800';
            app.TransmitLampLabel = uilabel(app.Panel);
            app.TransmitLampLabel.Text = 'Transmit';
            app.Transmit_Lamp = uilamp(app.Panel);
            app.ReceiveLampLabel = uilabel(app.Panel);
            app.ReceiveLampLabel.Text = 'Receive';
            app.Receive_Lamp = uilamp(app.Panel);
            app.Scale_Power = uigauge(app.Panel, 'ninetydegree');
            app.Scale_Power.MajorTicks = [0 25 50 75 100];
            app.Scale_Power.ScaleDirection = 'counterclockwise';
            app.Connect_Switch = uiswitch(app.Panel, 'slider');
            app.Disconnect = uibutton(app.Panel, 'push');
            app.Disconnect.Text = 'Disconnect';
            app.Panel_2 = uipanel(app.Panel);
            app.Panel_3 = uipanel(app.Panel);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Текст по морзянке (отправленный)';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_Morze()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end